classdef Galil_GUI_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HomingButton                    matlab.ui.control.Button
        TimebasedCtrlButton             matlab.ui.control.Button
        updatedonApril182023Label       matlab.ui.control.Label
        ManualAngledegEditFieldLabel_3  matlab.ui.control.Label
        ManualAngledegEditFieldLabel_2  matlab.ui.control.Label
        ManualRotationAngleEditField    matlab.ui.control.NumericEditField
        ManualRotationAngleEditFieldLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        NumberofSliceGauge              matlab.ui.control.Gauge
        NumberofSliceGaugeLabel         matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        QuitButton                      matlab.ui.control.Button
        ScanstepButton                  matlab.ui.control.Button
        RegistrationButton              matlab.ui.control.Button
        SpeedControlAutoVSlider         matlab.ui.control.Slider
        SpeedControlAutoVSliderLabel    matlab.ui.control.Label
        OUTCCWButton                    matlab.ui.control.Button
        OUTCWButton                     matlab.ui.control.Button
        GUIforMRIConditionalPAUSProbeActuationLabel  matlab.ui.control.Label
        SpeedControlManualVSlider       matlab.ui.control.Slider
        SpeedControlManualVSliderLabel  matlab.ui.control.Label
        INCWButton                      matlab.ui.control.Button
        INCCWButton_2                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INCCWButton_2
        function INCCWButton_2Pushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "l";
            val_struct.mode_flag = 3;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: INCWButton
        function INCWButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "k";
            val_struct.mode_flag = 4;         
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Value changed function: SpeedControlManualVSlider
        function SpeedControlManualVSliderValueChanged(app, event)
            load('PALap_Ryo/realtime.mat')
            value = app.SpeedControlManualVSlider.Value;
            val_struct.vel_manual = value;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Value changed function: SpeedControlAutoVSlider
        function SpeedControlAutoVSliderValueChanged(app, event)
            load('PALap_Ryo/realtime.mat')
            value = app.SpeedControlAutoVSlider.Value; % uncomment for
%             actual use
%             app.SpeedControlAutoVSlider.Value = val_struct.vel_auto;
            val_struct.vel_auto = value; % uncomment for actual use
%             val_struct.vel_auto = 0.01;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: ScanstepButton
        function ScanstepButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "s";
            val_struct.mode_flag = 5;
            val_struct.count = val_struct.count;
            app.NumberofSliceGauge.Value = val_struct.count;
            if app.NumberofSliceGauge.Value >= 180
                val_struct.lamp = 'red';
                app.Lamp.Color = val_struct.lamp;
                app.ScanstepButton.BackgroundColor = [0.65,0.65,0.65];                
            end

            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "r";
            val_struct.mode_flag = 6;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "q";
            val_struct.mode_flag = 7;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Callback function
        function CountResetButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')   
            val_struct.key = "q";
            val_struct.mode_flag = 7;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % insert realtime.mat -> raise flag so that the back end can restart (start end side before this code)          
            app.SpeedControlAutoVSlider.Value = 1;
            app.SpeedControlManualVSlider.Value = 2;
            app.ScanstepButton.BackgroundColor = [0.00,0.45,0.74];
            app.NumberofSliceGauge.Value = 0;
            app.Lamp.Color = 'green';
            app.ManualRotationAngleEditField.Value = 10;
            val_struct.count = 0;         
            val_struct.key = "nan";
            val_struct.mode_flag = -1;
            val_struct.vel_manual = app.SpeedControlManualVSlider.Value;
            val_struct.vel_auto = app.SpeedControlAutoVSlider.Value; % uncomment for actual use
            val_struct.angle_manu = app.ManualRotationAngleEditField.Value;
%             val_struct.vel_auto = 2.0; % just for test
            val_struct.lamp = app.Lamp.Color;
            save('PALap_Ryo/realtime.mat',"val_struct")          
        end

        % Value changed function: ManualRotationAngleEditField
        function ManualRotationAngleEditFieldValueChanged(app, event)
            value = app.ManualRotationAngleEditField.Value;
            load('PALap_Ryo/realtime.mat')
            val_struct.angle_manu = value;           
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: OUTCWButton
        function OUTCWButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "o";
            val_struct.mode_flag = 1;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: OUTCCWButton
        function OUTCCWButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "i";
            val_struct.mode_flag = 2;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: TimebasedCtrlButton
        function TimebasedCtrlButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "t";
            val_struct.mode_flag = 9;
%             val_struct.count = val_struct.count;
%             app.NumberofSliceGauge.Value = val_struct.count;
%             if app.NumberofSliceGauge.Value >= 180
%                 val_struct.lamp = 'red';
%                 app.Lamp.Color = val_struct.lamp;
                app.ScanstepButton.BackgroundColor = [0.65,0.65,0.65];                
            save('PALap_Ryo/realtime.mat',"val_struct")
        end

        % Button pushed function: HomingButton
        function HomingButtonPushed(app, event)
            load('PALap_Ryo/realtime.mat')
            val_struct.key = "u";
            val_struct.mode_flag = 11;
            save('PALap_Ryo/realtime.mat',"val_struct")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.INCCWButton_2 = uibutton(app.UIFigure, 'push');
            app.INCCWButton_2.ButtonPushedFcn = createCallbackFcn(app, @INCCWButton_2Pushed, true);
            app.INCCWButton_2.Text = 'IN CCW';
            app.INCWButton = uibutton(app.UIFigure, 'push');
            app.INCWButton.ButtonPushedFcn = createCallbackFcn(app, @INCWButtonPushed, true);
            app.INCWButton.Text = 'IN CW';
            app.SpeedControlManualVSliderLabel = uilabel(app.UIFigure);
            app.SpeedControlManualVSliderLabel.Text = 'Speed Control Manual [V]';
            app.SpeedControlManualVSlider = uislider(app.UIFigure);
            app.SpeedControlManualVSlider.Limits = [0 3.2];
            app.SpeedControlManualVSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedControlManualVSliderValueChanged, true);
            app.GUIforMRIConditionalPAUSProbeActuationLabel = uilabel(app.UIFigure);
            app.GUIforMRIConditionalPAUSProbeActuationLabel.Text = 'GUI for MRI-Conditional PA/US Probe Actuation';
            app.OUTCWButton = uibutton(app.UIFigure, 'push');
            app.OUTCWButton.ButtonPushedFcn = createCallbackFcn(app, @OUTCWButtonPushed, true);
            app.OUTCWButton.Text = 'OUT CW';
            app.OUTCCWButton = uibutton(app.UIFigure, 'push');
            app.OUTCCWButton.ButtonPushedFcn = createCallbackFcn(app, @OUTCCWButtonPushed, true);
            app.OUTCCWButton.Text = 'OUT CCW';
            app.SpeedControlAutoVSliderLabel = uilabel(app.UIFigure);
            app.SpeedControlAutoVSliderLabel.Text = 'Speed Control Auto [V]';
            app.SpeedControlAutoVSlider = uislider(app.UIFigure);
            app.SpeedControlAutoVSlider.Limits = [0 3.2];
            app.SpeedControlAutoVSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedControlAutoVSliderValueChanged, true);
            app.RegistrationButton = uibutton(app.UIFigure, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Text = 'Registration';
            app.ScanstepButton = uibutton(app.UIFigure, 'push');
            app.ScanstepButton.ButtonPushedFcn = createCallbackFcn(app, @ScanstepButtonPushed, true);
            app.ScanstepButton.Text = 'Scan (step)';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.NumberofSliceGaugeLabel = uilabel(app.UIFigure);
            app.NumberofSliceGaugeLabel.Text = 'Number of Slice';
            app.NumberofSliceGauge = uigauge(app.UIFigure, 'circular');
            app.NumberofSliceGauge.Limits = [0 180];
            app.Lamp = uilamp(app.UIFigure);
            app.ManualRotationAngleEditFieldLabel = uilabel(app.UIFigure);
            app.ManualRotationAngleEditFieldLabel.Text = 'Manual Rotation Angle';
            app.ManualRotationAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ManualRotationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ManualRotationAngleEditFieldValueChanged, true);
            app.ManualAngledegEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ManualAngledegEditFieldLabel_2.Text = '[deg]';
            app.ManualAngledegEditFieldLabel_3 = uilabel(app.UIFigure);
            app.ManualAngledegEditFieldLabel_3.Text = '*only absolute value';
            app.updatedonApril182023Label = uilabel(app.UIFigure);
            app.updatedonApril182023Label.Text = 'updated on April 18, 2023';
            app.TimebasedCtrlButton = uibutton(app.UIFigure, 'push');
            app.TimebasedCtrlButton.ButtonPushedFcn = createCallbackFcn(app, @TimebasedCtrlButtonPushed, true);
            app.TimebasedCtrlButton.Text = 'Time-based Ctrl';
            app.HomingButton = uibutton(app.UIFigure, 'push');
            app.HomingButton.ButtonPushedFcn = createCallbackFcn(app, @HomingButtonPushed, true);
            app.HomingButton.Text = 'Homing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Galil_GUI_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end